classdef ps2000aMultimeterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ConnectedLamp          matlab.ui.control.Lamp
        ConnectedLabel         matlab.ui.control.Label
        Switch                 matlab.ui.control.RockerSwitch
        ModeSelectorKnob       matlab.ui.control.DiscreteKnob
        ModeSelectorKnobLabel  matlab.ui.control.Label
        ValueDisplayEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        ps2000aDeviceObj % device object for controling the scope
        status % status returns from functions
        configs % configuration values
        mode % masurement mode
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.configs.ps2000aStructs, app.configs.ps2000aEnumInfo, ...
                app.configs.ps2000aWrapStructs, app.configs.ps2000aWrapEnuminfo] = ps2000aSetConfig();

            % Create a device object. 
            % The serial number can be specified as a second input parameter.
            app.ps2000aDeviceObj = icdevice('picotech_ps2000a_generic.mdd', '');
    
            % Connect device object to hardware.

            connect(app.ps2000aDeviceObj)

            %turn off all channels
            [app.status.setChA] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 0, 0, 1, 8, 0.0);
            [app.status.setChB] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 1, 0, 1, 8, 0.0);

            if (app.ps2000aDeviceObj.channelCount == PicoConstants.QUAD_SCOPE)
    
                [app.status.setChC] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 2, 0, 1, 8, 0.0);
                [app.status.setChD] = invoke(app.ps2000aDeviceObj, 'ps2000aSetChannel', 3, 0, 1, 8, 0.0);
    
            end

            if app.ps2000aDeviceObj.DriverData.unitHandle > 0

                app.ConnectedLamp.Color = [0.00,1.00,0.00];
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Disconnect device
            % Disconnect device object from hardware.

            disconnect(app.ps2000aDeviceObj);
            delete(app.ps2000aDeviceObj);
            delete(app)
        end

        % Value changed function: ModeSelectorKnob
        function ModeSelectorKnobValueChanged(app, event)
            app.mode = app.ModeSelectorKnob.Value;
            app.Switch.Value = 0;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ValueDisplayEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueDisplayEditField.Editable = 'off';
            app.ModeSelectorKnobLabel = uilabel(app.UIFigure);
            app.ModeSelectorKnobLabel.Text = 'Mode Selector';
            app.ModeSelectorKnob = uiknob(app.UIFigure, 'discrete');
            app.ModeSelectorKnob.Items = {'Off', 'V DC', 'V AC RMS', 'A DC', 'A AC RMS', 'Resistance DC', 'Resistance AC RMS'};
            app.ModeSelectorKnob.ValueChangedFcn = createCallbackFcn(app, @ModeSelectorKnobValueChanged, true);
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'Stopped', 'Running'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.Value = '0';
            app.ConnectedLabel = uilabel(app.UIFigure);
            app.ConnectedLabel.Text = 'Connected ?';
            app.ConnectedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ps2000aMultimeterApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end